classdef morse < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MorseCodeUIFigure           matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        SendTab                     matlab.ui.container.Tab
        UIAxes                      matlab.ui.control.UIAxes
        SendButton                  matlab.ui.control.Button
        TextTextAreaLabel           matlab.ui.control.Label
        TextTextArea                matlab.ui.control.TextArea
        MorseTextAreaLabel          matlab.ui.control.Label
        MorseTextArea               matlab.ui.control.TextArea
        WordsperMinuteSpinnerLabel  matlab.ui.control.Label
        WordsperMinuteSpinner       matlab.ui.control.Spinner
        CarrierFrequencyLabel       matlab.ui.control.Label
        CarrierFrequencySpinner     matlab.ui.control.Spinner
        ReceiveTab                  matlab.ui.container.Tab
        ListenSwitchLabel           matlab.ui.control.Label
        ListenSwitch                matlab.ui.control.Switch
        UIAxes2                     matlab.ui.control.UIAxes
        ReceivedTextTextAreaLabel   matlab.ui.control.Label
        ReceivedTextTextArea        matlab.ui.control.TextArea
        SaveButton                  matlab.ui.control.StateButton
        ClearButton                 matlab.ui.control.StateButton
    end


    properties (Access = private)
        closing_req = false;
        unused_times = [];
        last_sound_on = false;
        is_silent = true;
        ticks = 0;
        time_step;
    end
    
    properties(Constant, Access = private)
        dit = '.';
        dah = '-';
        wordsep = ' ';
        lettersep = '|';
        word_length = 50;
        sampling_rate = 44100;
        frames_per_sample = 1024;
        vol_threshold = -50;
        flush_time = 500;
        noise_threshold = 1;
    end
        
    methods (Access = public)

        function wave = morse2wave(app, morse)
            app.SendButton.Enable = false;
            
            [b, a] = butter(4, app.CarrierFrequencySpinner.Value / (app.sampling_rate / 4));
            
            dit_duration = 60 / (app.WordsperMinuteSpinner.Value * app.word_length); 
            dah_duration = dit_duration * 3;
            dit_samples = floor(dit_duration  * app.sampling_rate);
            dah_samples = floor(dah_duration  * app.sampling_rate);
            
            int_el_duration = dit_duration;
            short_duration = dah_duration;
            medium_duration = dit_duration * 7;
            int_el_samples = floor(int_el_duration  * app.sampling_rate);
            short_samples = floor(short_duration * app.sampling_rate);
            medium_samples = floor(medium_duration * app.sampling_rate);
            
            dit_wave = audioOscillator('sine', app.CarrierFrequencySpinner.Value, 'SampleRate', app.sampling_rate, 'SamplesPerFrame', dit_samples);
            dah_wave = audioOscillator('sine', app.CarrierFrequencySpinner.Value, 'SampleRate', app.sampling_rate, 'SamplesPerFrame', dah_samples);

            wave = [];
            
            for i = 1:length(morse)
                if morse(i) == app.dit
                    wave = [wave; dit_wave(); zeros(int_el_samples, 1)];
                
                elseif morse(i) == app.dah
                    wave = [wave; dah_wave(); zeros(int_el_samples, 1)];
                
                elseif morse(i) == app.lettersep
                    wave = [wave; zeros(short_samples - int_el_samples, 1)];
                
                elseif morse(i) == app.wordsep
                    wave = [wave; zeros(medium_samples - int_el_samples, 1)];
                end
            end
            
            wave = filter(b, a, wave);
            player = audioplayer(wave, app.sampling_rate, 16);
            playblocking(player);
            app.SendButton.Enable = true;
        end
    
        function message = morse_decode(app, morse)
        
            current = '';
            message = '';
            
            for k = 1:length(morse)
                if morse(k) == app.dit || morse(k) == app.dah
                    current = [current, morse(k)];
                
                elseif morse(k) == app.lettersep
                    message = [message, app.morse_decode_char(current)];
                    current = '';
                
                elseif morse(k) == app.wordsep
                    message = [message, app.morse_decode_char(current), ' '];
                    current = '';
                
                else
                    warning(['Invalid Morse code "', morse(k), '" on message "', morse, '".']);
                end
            end
            if current
                message = [message, app.morse_decode_char(current)];
            end
        end
        
        function pretty = printable_morse(app, txt)
            pretty = strrep(app.morse_encode(txt), app.wordsep, '      ');
            pretty = strrep(pretty, app.lettersep, '  ');
            pretty = strrep(pretty, app.dah, '▬ ');
            pretty = strrep(pretty, app.dit, '● ');
        end
    
        function ch = morse_decode_char(~, morse)
        % Based on https://stackoverflow.com/questions/28045172/morse-code-converter-in-c#28046691
        % Uses a dichotomic search tree to parse each morse sign (* means no character
        % is mapped to that combination).
            tree = ['*ETIANM' ...   % first (empty), second and third lines of tree
                        'SURWDKGO' ...  % fourth line of tree
                        'HVF*L*PJ' ...  % first half of fifth line of tree
                        'BXCYZQ**' ...  % second half of fifth line of tree
                        '54*3***2' ...  % first quarter of sixth line of tree
                        '**+****1' ...  % second quarter of sixth line of tree
                        '6=/***(*' ...  % third quarter of sixth line of tree
                        '7***8*90' ...  % fourth quarter of sixth line of tree
                        '********' ...  % first eighth of seventh line of tree
                        '****?***' ...  % second eighth of seventh line of tree
                        '**"**.**' ...  % third eighth of seventh line of tree
                        '**@***''*' ... % fourth eighth of seventh line of tree
                        '*-******' ...  % fifth eighth of seventh line of tree
                        '*****)**' ...  % sixth eighth of seventh line of tree
                        '***,****' ...  % seventh eighth of seventh line of tree
                        ':*******'];    % eighth eighth of seventh line of tree 
            
            if length(morse) <= 6
                j = 1;
                for k = 1:length(morse)
                    if morse(k) == '.'
                        j = 2 * j;
                    else
                        j = 2 * j + 1;
                    end
                end
                ch = tree(j);
            
            elseif morse == "........"
                ch = '<ERROR>';    % special case: error sign is 8 dits long (........)
            end
            
            if ch == '*'
                warning(['Invalid Morse sign "', ch, '" on message "', morse, '".']);
            end
        end
        
        function morse = morse_encode(app, message) 
            % Encodes a message from ASCII to Morse code, with separators for letters and words
    
            table = {
                '-.-.--', '.?..?.', '', '', ...                  % symbols !"#$
                '', '', ...                                      % symbols %&
                '.????.', '?.??.', ...                         % symbols '(
                '?.??.?', '', '.-.-.', '--..--', ...            % symbols )*+,
                '-....-', '.-.-.-', '-..-.', ...                 % symbols -./
                '-----', '.----', '..---', '...--', '....-', ... % numbers 0-4
                '.....', '-....', '--...', '---..', '----.', ... % numbers 5-9
                '---...', '', '', '-...-', ...                   % symbols :;<=
                '', '..--..', '.--.-.', ...                      % symbols >?@
                '.-', '-...', '-.-.', '-..', ...                 % ABCD
                '.', '..-.', '--.', '....', ...                 % EFGH
                '..', '.---', '-.-', '.-..',  ...               % IJKL
                '--', '-.', '---', '.--.', ...                   % MNOP
                '--.-', '.-.', '...', '-', ...                   % QRST
                '..-', '...-', '.--', '-..-', ...                % UVWX
                '-.--', '--..', ...                              % YZ
                '', '', '', '', '', '', ...                      % symbols [\]^_`
                '.-', '-...', '-.-.', '-..', ...                 % abcd
                '.', '..-.', '--.', '....', ...                  % efgh
                '..', '.---', '-.-', '.-..', ...                 % ijkl
                '--', '-.', '---', '.--.', ...                   % mnop
                '--.-', '.-.', '...', '-', ...                   % qrst
                '..-', '...-', '.--', '-..-', ...                % uvwx
                '-.--', '--..', ...                              % yz
                '', '', '', ''  ...                              % symbols {|}~
            };
            morse = '';
            for k = 1:length(message)
                if char(message(k)) > 32 && char(message(k)) < 127
                    [morse] = [morse, table{message(k) - 32}, app.lettersep];
                elseif message(k) == ' '
                    [morse] = [morse(1:end-1), app.wordsep];
                end
            end
            
            morse = morse(1:end-1);
        end
        
        function listen_morse(app)
            deviceReader = audioDeviceReader;            
            
            maxFreq = app.sampling_rate / 2;
            freqBins = app.frames_per_sample / 2;
            freqStep = maxFreq / freqBins;
            
            min_f = 500;
            max_f = 600;
            f = min(min_f, max_f);
            min_bin = min(max(round(f / freqStep), 1), freqBins) + 1;
            f = min(max(max_f, 0), maxFreq);
            max_bin = min(max(round(f / freqStep), 1), freqBins) + 1;
            
            app.UIAxes2.YLim = [-150 0];
            app.UIAxes2.XLim = [0 maxFreq];
      
            while app.ListenSwitch.Value == '1' && ~app.closing_req
                x = deviceReader();
                xdft = fft(x);
                psdx = (1/(app.sampling_rate*app.frames_per_sample)) * abs(xdft(1:freqBins+1)).^2;
                psdx(2:end-1) = 2*psdx(2:end-1);
                psdx = 10*log10(psdx);
                freq = 0:freqStep:maxFreq;
                plot(app.UIAxes2, freq, psdx, 'b');
                app.process_record(mean(psdx(min_bin:max_bin)) > app.vol_threshold);
                drawnow;
            end
            
            release(deviceReader);
            
            if app.closing_req
                app.ListenSwitch.Value = '1';
                app.delete();
            end
        end

        
        function process_record(app, is_on)
             
            if app.is_silent
                if ~is_on
                    return;
                else
                    app.is_silent = false;
                    app.ticks = 0;
                    app.last_sound_on = true;
                end
            end
            if is_on == app.last_sound_on
                app.ticks = app.ticks + 1;
                if app.ticks * app.time_step > app.flush_time
                    app.flush(is_on);
                    app.ticks = 0;
                end
            else
                app.flush(~is_on);
                app.last_sound_on = is_on;
                app.ticks = 1;
            end    
        end
        
        function flush(app, is_on)
            if is_on
                app.add_timing(app.ticks * app.time_step);
            else
                app.add_timing(-app.ticks * app.time_step);
            end
        end
    
        function add_timing(app, duration)
            if timing == 0
                return;
            end
            if ~isempty(app.unused_times)
                last = app.unused_times(end);
                if duration * last > 0
                    app.unused_times(end) = [];
                    duration = last + duration;
                elseif abs(duration) <= =app.noise_threshold
                    app.unused_times(end) = [];
                    duration = last - duration;
                end
            end
            app.unused_times = [app.unused_times duration];
            if -duration >= app.dit_dah_threshold
                app.flush(app.last_sound_on);
            end
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.time_step = 1000 / (app.sampling_rate / app.frames_per_sample);
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            txt = upper(strrep(char(app.TextTextArea.Value), '\n', ' '));
            if (length(txt) < 5) || ~(strcmp(txt(1:5), 'DRILL'))
                txt = ['DRILL '  txt];
            end
            txt = strrep(txt, '\n', ' ');
            morse = app.printable_morse(txt);
            app.MorseTextArea.Value = string(morse);
            app.TextTextArea.Value = string(txt);
            
            if strcmp(app.TextTextArea.Value, "")
                return;
            end
            
            signal = app.morse2wave(app.morse_encode(char(app.TextTextArea.Value)));
            plot(app.UIAxes, (0:length(signal)-1) / app.sampling_rate, signal);
            app.UIAxes.XLim = [0 length(signal)-1] / app.sampling_rate;
            app.UIAxes.Visible = true;
        end

        % Value changed function: ListenSwitch
        function ListenSwitchValueChanged(app, event)
            if app.ListenSwitch.Value == '1'
                app.listen_morse();
            end
        end

        % Close request function: MorseCodeUIFigure
        function MorseCodeUIFigureCloseRequest(app, event)
            if app.ListenSwitch.Value == '1'
                app.closing_req = true; 
                return
            end
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MorseCodeUIFigure = uifigure;
            app.MorseCodeUIFigure.Name = 'Morse Code';
            app.MorseCodeUIFigure.CloseRequestFcn = createCallbackFcn(app, @MorseCodeUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.MorseCodeUIFigure);
            app.SendTab = uitab(app.TabGroup);
            app.SendTab.Title = 'Send';
            app.UIAxes = uiaxes(app.SendTab);
            title(app.UIAxes, 'Morse signal')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude (a.u.)')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [-1.2 1.2];
            app.SendButton = uibutton(app.SendTab, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.TextTextAreaLabel = uilabel(app.SendTab);
            app.TextTextAreaLabel.Text = 'Text';
            app.TextTextArea = uitextarea(app.SendTab);
            app.MorseTextAreaLabel = uilabel(app.SendTab);
            app.MorseTextAreaLabel.Text = 'Morse';
            app.MorseTextArea = uitextarea(app.SendTab);
            app.MorseTextArea.Editable = 'off';
            app.WordsperMinuteSpinnerLabel = uilabel(app.SendTab);
            app.WordsperMinuteSpinnerLabel.Text = 'Words per Minute';
            app.WordsperMinuteSpinner = uispinner(app.SendTab);
            app.WordsperMinuteSpinner.Limits = [1 60];
            app.WordsperMinuteSpinner.RoundFractionalValues = 'on';
            app.WordsperMinuteSpinner.Value = 20;
            app.CarrierFrequencyLabel = uilabel(app.SendTab);
            app.CarrierFrequencyLabel.Text = 'Carrier Frequency';
            app.CarrierFrequencySpinner = uispinner(app.SendTab);
            app.CarrierFrequencySpinner.Step = 50;
            app.CarrierFrequencySpinner.Limits = [200 5000];
            app.CarrierFrequencySpinner.RoundFractionalValues = 'on';
            app.CarrierFrequencySpinner.Value = 500;
            app.ReceiveTab = uitab(app.TabGroup);
            app.ReceiveTab.Title = 'Receive';
            app.ListenSwitchLabel = uilabel(app.ReceiveTab);
            app.ListenSwitchLabel.Text = 'Listen';
            app.ListenSwitch = uiswitch(app.ReceiveTab, 'slider');
            app.ListenSwitch.ItemsData = {'0', '1'};
            app.ListenSwitch.ValueChangedFcn = createCallbackFcn(app, @ListenSwitchValueChanged, true);
            app.ListenSwitch.Value = '0';
            app.UIAxes2 = uiaxes(app.ReceiveTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.ReceivedTextTextAreaLabel = uilabel(app.ReceiveTab);
            app.ReceivedTextTextAreaLabel.Text = 'Received Text';
            app.ReceivedTextTextArea = uitextarea(app.ReceiveTab);
            app.SaveButton = uibutton(app.ReceiveTab, 'state');
            app.SaveButton.Text = 'Save';
            app.ClearButton = uibutton(app.ReceiveTab, 'state');
            app.ClearButton.Text = 'Clear';
        end
    end

    methods (Access = public)

        % Construct app
        function app = morse

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MorseCodeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MorseCodeUIFigure)
        end
    end
end